classdef app_heartbeat_detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LatidoLampLabel            matlab.ui.control.Label
        LatidoLamp                 matlab.ui.control.Lamp
        SeleccioneunarchivoButton  matlab.ui.control.StateButton
        ReproducirButton           matlab.ui.control.StateButton
        UIOriginalSignal           matlab.ui.control.UIAxes
        UIFilteredSignal           matlab.ui.control.UIAxes
        UIProcessedSignal          matlab.ui.control.UIAxes
        DetenerButton              matlab.ui.control.Button
        UIRRSignalLength           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        signalStruct;       % estructura que se obtiene al cargar un achivo .mat
        signal;             % señal ecg que se carga directamente de un archivo
        timedEcg;           % señal ecg que va creciendo con el tiempo
        
        % aquí van variables necesarias para poder hacer los cálculos de la
        % señal
        
        Fs;     % frecuencia de muestreo 
        L;      % longitud de la señal
        T;      % perido de la señal 
        t;      % vector de tiempo de la señal ecg
              
        
        file;       % nombre del archivo 
        path;       % ruta al archivo 
        
        beep;       % sonido de un beep para indicar detección de complejo QRS
        beepFs;     % frecuencia de muestreo del audio de beep
        
        % variable para detectar si la señal fue inicializada
        initialized = false;    
        
        % variable para detener al programa
        stopRunning = false;  
        
    end
    
    
    methods (Access = public)
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %   FUNCIÓN QUE SIMULA EL PROCESO DE ENTRADA DE DATOS    %   
        %   Y DETECCIÓN DE COMPLEJOS QRS                         %   
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function startSimulation(app)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% SECCIÓN DE INICIALIZACIÓN DE VARIABLES ÚTILES PARA EL        %
            %% ALGORITMO                                                    %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            app.L = length(app.signal);         % Longitud de la señal (numero de muestras)
            app.T = app.L/app.Fs;               % Duración de la señal
            app.t = linspace(0, app.T, app.L);  % Vector de tiempo
            
            % ancho de la ventana que se va moviendo y que va integrando
            % el ancho de la ventana es de 150 ms entonces se debe tener en cuenta
            % la frecuencia de muestreo de la señal particular para calcular el número
            % de muestras que va a contener la ventana.
            
            n = ceil(150/((app.Fs.^(-1))*1000));
            
            % promedios de los 8 más grandes intervalos RR, y de los más recientes
            % 8 intervalos RR, respectivamente 
            
            RRavg2 = 0;
            RRavg1 = 0;
            
            % lista de intervalos RR 
            RR1 = [ ];
            
            % conjunto de umbrales para la señal filtrada y la integrada
            % respectivamente
            thresholdSetF = [];
            thresholdSetI = [];
            
            
            % media del pico de la señal filtrada
            SPKF = 0;
            
            % media del pico de ruido de la señal  filtrada 
            NPKF = 0;
            
            % media del pico de la señal integrada
            SPKI = 0;
            
            % media del pico del ruido de la señal integrada
            NPKI = 0;

            
            % variable de lectura de la señal para simulaciones
            localSignal = zeros(app.L,1);
            localFilteredSignal = zeros(app.L,1);
            derivative = zeros(app.L,1);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% recibe todas las señales hasta tener cantidad suficiente
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            localSignal(1:n) = app.signal(1:n);
            
            % para hacer el filtrado con el filtro original del paper es mejor hacerlo
            % a pedazos porque si se trata hacer todo de una sola vez el algoritmo
            % recursivo de filtrado tarda un siglo 
            
            localFilteredSignal(1: floor(n/2) ) = passbandFilter(localSignal( 1:floor(n/2) ));
            localFilteredSignal( floor(n/2)+1: n) = passbandFilter(localSignal( floor(n/2)+1 :n ));
            
            derivative(3:n-2) = customDerivative(app.signal(1:n), app.T); 
            squared = derivative.^2;
            integral = [zeros(n,1); movingIntegrator(squared, n)'];

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% inicializa algunas variables previas que son también útiles 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % indices usados para calcular los intervalos RR
            prevQRSindex = 0;
            beginning = (n+1) + 2;
            
            % tiempo que debe esperar para inicializar la detección de umbrales
            twoSeconds = ceil(2000/((app.Fs.^(-1))*1000));
            
            % tiempo en el que tras ocurrir un latido no puede ocurrir otro 
            % (lo dice el paper, son 200 ms, en este caso se pasan esos segundos 
            % a número de muestras en que se alcanzan esos segundos). 
            
            refatoryPeriod = ceil(200/((app.Fs.^(-1))*1000));
            
            % variable para indicar que está en periodo de espera para poder detectar 
            % otro QRS
            
            isInRefatoryPeriod = false;
            
            
            % variable para guardar el pico máximo que se va encontrando a lo largo del
            % proceso de busqueda de picos candidatos a ser complejo QRS
            
            maxPeakF = -Inf;
            maxPeakI = -Inf;
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% EMPIEZA A ANALIZAR LA SEÑAL EN TIEMPO REAL       %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            for i = beginning:app.L-2
                
                pause(0.01);
                if (app.stopRunning)
                   
                    disp("Simulación detenida.");
                    while (app.stopRunning)
                        
                        pause(0.01);
                        
                    end
                    disp("Simulación reanudada.");
                end
                
                
                % inicializa prevQRSindex
                if (i == beginning)
                    prevQRSindex = i;
                end
                
                %obtiene la muestra actual
                sample = app.signal(i);
                localSignal(i) = sample;
                
                % filtrado de la señal para solo pasar de 5 Hz a 15 Hz 
                % con passbandFilter se implementa literalmente el filtro 
                % de pam-tompkins en su paper 
                
                filteredLocalSignal = passbandFilter(localSignal(i-34:i));
                localFilteredSignal(i) = filteredLocalSignal(end);
                
                derivative(i-2) = (1/(8.*app.T)) .* ( (2*localFilteredSignal(i+1) + ...
                               localFilteredSignal(i+2) - localFilteredSignal(i-2)...
                               - 2*localFilteredSignal(i-1)) );
                           
                squared(i-2) = derivative(i-2).^2;
                integral(i-2) = (1/n).* (sum(squared(i-2-n: i-2)));
                
                % Hace los plots (observamos que el cuello de botella está en la 
                % graficación no en el procesamiento de los datos )
                
                plot(app.UIOriginalSignal, app.t(1:i-2), localSignal(1:i-2));

                plot(app.UIFilteredSignal, app.t(1:i-2), localFilteredSignal(1:i-2));

                plot(app.UIProcessedSignal, app.t(1:i-2), integral(1:i-2));

                plot(app.UIRRSignalLength, 1:length(RR1), fliplr(RR1).*(app.Fs.^-1).*(1000), 'b--o');
                
                if i ~= app.L-2
                    pause(0.01);

                end
                
            
                % Al pasar dos segundos hacemos la inicialización
                if i == twoSeconds
             
                    % inicializamos el SPKI
                    peaksFiltered = findpeaks(  integral(1:i) );
                    [SPKI, idx] = max(peaksFiltered);
                    SPKI = SPKI/3;
                    
                    % inicializamos el NPKI
                    NPKI = mean(peaksFiltered( 1:length(peaksFiltered) ~= idx ))/2;
                    
                    % inicializamos el SPKF
                    peaksFiltered = findpeaks( localFilteredSignal(1:i) );
                    [SPKF, idx] = max(peaksFiltered);
                    SPKF = SPKF/3;
                    
                    % inicializamos el NPKF
                    NPKF = mean(peaksFiltered( 1:length(peaksFiltered) ~= idx ))/2;
                           
                    % Inicializamos el conjunto de umbrales para la señal filtrada 
                    thresholdSetF(1) = NPKF + 0.25*(SPKF - NPKF);
                    thresholdSetF(2) = thresholdSetF(1) * 0.5;
                    
                    % Inicializamos el conjunto de umbrales para la señal integrada
                    thresholdSetI(1) = NPKI + 0.25*(SPKI - NPKI);
                    thresholdSetI(2) = thresholdSetI(1) * 0.5;
                    
                    % Inicialización del vector de intervalos RR1
                    % obseve que aquí se hace con base a la señal filtrada
                    
                    [peaks, peaksIdxs] = findpeaks(localFilteredSignal(1:i));
                    [maxval, ~] = max(peaks); 
            
                    % tras tener el máximo de la señal busca cuales 
                    % son los picos que tienen 80% o más altura 
                    % que ese pico máximo encontrado, de esta forma se busca detectar
                    % los picos que pueden haber en ese intervalo inicial 
            
                    idxs = find( peaks >= (5/10).*maxval); 
                    
                    % captura los indices en 'localFilteredSignal' de los picos más
                    % altos
                    idxs = peaksIdxs(idxs);
                    
                    if length(idxs) >= 2
                        
                        localPrevIdx = idxs(1);
                        
                        for k = 2:length(idxs)
                            
                            RR = idxs(k) - localPrevIdx;
                            RR1 = [ RR RR1];
                            localPrevIdx = k;
                        end
                        
                    end
                    
                    prevQRSindex = idxs(end);
                    
                    %inicializa RRavg2
                    RRavg2 = mean(RR1);
                    
                    RRlow = RRavg2 * 0.92;
                    RRhigh = RRavg2 * 1.16;
                    RRmiss = RRavg2 * 1.66;
                
                % si llevamos más de los 2 segundos y ya inicializamos entonces 
                % hacemos el proceso de detección de QRS y actualización de variables
                
                elseif i > twoSeconds
                           
                    isQRSFilt = false;
                    isQRSInt = false;
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %   Detecta con los umbrales si hay o no un potencial QRS %
                    %   en la señal que ha sido ** FILTRADA **                %  
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    
                    if localFilteredSignal(i-2) > thresholdSetF(1)
                    
                        isQRSFilt = true;  
                        SPKF = (0.125 * localFilteredSignal(i-2)) + 0.875 * SPKF;
                        
                        % resetea el pico máximo de la señal filtrada
                        maxPeakF = -Inf;
                                    
                    % caso en que tiene que usar el 2do umbral para verificar 
                    % si la señal pico es un QRS
                    elseif (RRmiss == (i - 2 - prevQRSindex) )
                        
                        % si el pico máximo encontrado a lo largo de este tiempo 
                        % es más grande que el segundo umbral entonces ese pico 
                        % se categoriza como un complejo QRS 
                        
                        if maxPeakF > thresholdSetF(2)
                            
                            isQRSFilt = true;                
                            SPKF = (0.25 * maxPeakF) + 0.75 * SPKF;
                            
                        % el caso en el que el pico más grande encontrado a lo largo de este
                        % periodo de búsqueda, no sea más grande que el segundo umbral
                        % se acutualiza el umbral de ruido
                        
                        else
                            
                            % actualizo el valor para el estimado de pico de ruido
                            NPKF = (maxPeakF * 0.125) + (NPKF * 0.875); 
                        end
                        
                        % resetea el pico máximo de la señal filtrada
                        maxPeakF = -Inf;
                        
                    % en caso de que aún no se esté en RRmiss y el dato detectado en el
                    % momento no sea mayor al umbral uno se revisa si se puede guardar
                    % como máximo pico 'temporal'
                    else
                        
                        if localFilteredSignal(i-2) > maxPeakF
                            maxPeakF = localFilteredSignal(i-2);
                        end
                            
                    end
                    
            
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %   Detecta con los umbrales si hay o no un potencial QRS %
                    %   en la señal que ha sido ** INTEGRADA **               %  
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    
                    if integral(i-2) > thresholdSetI(1)
                    
                        isQRSInt = true;            
                        SPKI = (0.125 * integral(i-2)) + 0.875 * SPKI;
                        
                        % resetea el pico máximo de la señal integrada
                        maxPeakI = -Inf;     
                        
                    % caso en que tiene que usar el 2do umbral para verificar 
                    % si la señal pico es un QRS
                    elseif (RRmiss == (i - 2 - prevQRSindex) )
                        
                        if maxPeakI > thresholdSetI(2)
                            
                            isQRSInt = true;               
                            SPKI = (0.25 * maxPeakI) + 0.75 * SPKI;
                        
                        else
                            
                            % actualizo el valor para el estimado de pico de ruido
                            NPKI = (maxPeakI * 0.125) + (NPKI * 0.875); 
                        end
                        
                        % resetea el pico máximo de la señal integrada
                        maxPeakI = -Inf;
                       
                    % en caso de que aún no se esté en RRmiss y el dato detectado en el
                    % momento no sea mayor al umbral uno se revisa si se puede guardar
                    % como máximo pico 'temporal'
                    
                    else
                        
                        if integral(i-2) > maxPeakI
                            maxPeakI = integral(i-2);
                        end
                    end
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    %   DETECCIÓN DE SEÑAL, RESETEO Y ACTUALIZACIÓN DE VARS     %
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    
                    % setea la variable que impide que suene multiples veces 
                    % la alarma en caso de detectar un QRS
                    
                    if ( (i - prevQRSindex) > refatoryPeriod)
                        isInRefatoryPeriod = false;
                    end
                    
                    % suena la alarma si ambos detectores dicen que hay un pico
                         
                    if (isQRSFilt && isQRSInt) && ( ~isInRefatoryPeriod )
                        
                        sound(app.beep, app.beepFs); 
                        isInRefatoryPeriod = true;  
                        app.LatidoLamp.Color = 'green';
                        pause(0.5);
                        app.LatidoLamp.Color = 'red';
                       
                        % calcula la longitud del intervalo RR actual
                        RR = (i - prevQRSindex);
                        
                        % resetea el contador
                        prevQRSindex = i;
                        
                        % añade el RR actual a la lista
                        RR1 = [RR RR1];
                                   
                        % saca la media de los 8 RR1 más recientes
                        if length(RR1) >= 8
                            RRavg1 = mean(RR1(1:8));
                        else
                            RRavg1 = mean(RR1( 1:length(RR1) ));
                        end
            
                        % saca la media de los 8 RR1 máximos más recientes
                        if length(RR1) >= 8
                            
                            sortedRR1 = sort(RR1, 'descend');
                            RRavg2 = mean(sortedRR1(1:8));
                        else
                            RRavg2 = mean(RR1( 1:length(RR1) ));
                        end
                       
            
                        % indice de intervalo RR bajo, alto y miss 
                        RRlow = RRavg2 * 0.92;
                        RRhigh = RRavg2 * 1.16;
                        RRmiss = RRavg2 * 1.66;
                      
                
                    end
            
                    
                end


            end


    end


end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SeleccioneunarchivoButton
        function SeleccioneunarchivoButtonValueChanged(app, event)
            %value = app.SeleccioneunarchivoButton.Value;
            
            [app.file, app.path] = uigetfile('*.mat');
            filepath = fullfile(app.path, app.file);

            disp(filepath);
            app.signalStruct = load(filepath);
            app.signal = app.signalStruct.ecg;
            app.Fs = app.signalStruct.Fs;
            
            % si no se ha inicializado inicializa el beep de la señal de
            % audio
            
            if (~ app.initialized )
                [app.beep, app.beepFs] = audioread('beep1.wav');
                app.initialized = true;
            end
            
            disp("Correctamente inicializado!");
            
            
        end

        % Value changed function: ReproducirButton
        function ReproducirButtonValueChanged(app, event)
            %value = app.ReproducirButton.Value;
            
            if (app.initialized)

                if (app.stopRunning)
                    app.stopRunning = false;
                    app.DetenerButton.Text = "Detener";
                end
                
                disp("Inicio de la simulación ...");
                startSimulation(app);
            end


        end

        % Button pushed function: DetenerButton
        function DetenerButtonPushed(app, event)
                
            if (~app.stopRunning)
                app.stopRunning = true;
                app.DetenerButton.Text = "Reanudar";
                disp("Se ha solicitado detener la simulación.");
            else
                app.stopRunning = false;
                app.DetenerButton.Text = "Detener";
                disp("Se ha solicitado reanudar la simulación.");
                
            end
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LatidoLampLabel = uilabel(app.UIFigure);
            app.LatidoLampLabel.Text = 'Latido';
            app.LatidoLamp = uilamp(app.UIFigure);
            app.SeleccioneunarchivoButton = uibutton(app.UIFigure, 'state');
            app.SeleccioneunarchivoButton.ValueChangedFcn = createCallbackFcn(app, @SeleccioneunarchivoButtonValueChanged, true);
            app.SeleccioneunarchivoButton.Text = 'Seleccione un archivo';
            app.ReproducirButton = uibutton(app.UIFigure, 'state');
            app.ReproducirButton.ValueChangedFcn = createCallbackFcn(app, @ReproducirButtonValueChanged, true);
            app.ReproducirButton.Text = 'Reproducir';
            app.UIOriginalSignal = uiaxes(app.UIFigure);
            title(app.UIOriginalSignal, 'Señal ECG original')
            xlabel(app.UIOriginalSignal, 'tiempo (s)')
            ylabel(app.UIOriginalSignal, 'mV')
            app.UIFilteredSignal = uiaxes(app.UIFigure);
            title(app.UIFilteredSignal, 'Señal ECG filtrada')
            xlabel(app.UIFilteredSignal, 'tiempo (s)')
            ylabel(app.UIFilteredSignal, 'mV')
            app.UIProcessedSignal = uiaxes(app.UIFigure);
            title(app.UIProcessedSignal, 'Integral de la derivada al cuadrado de la señal ECG filtrada')
            xlabel(app.UIProcessedSignal, 'tiempo (s)')
            ylabel(app.UIProcessedSignal, 'u.a')
            app.DetenerButton = uibutton(app.UIFigure, 'push');
            app.DetenerButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerButtonPushed, true);
            app.DetenerButton.Text = 'Detener';
            app.UIRRSignalLength = uiaxes(app.UIFigure);
            title(app.UIRRSignalLength, 'Duración de Intervalo RR')
            xlabel(app.UIRRSignalLength, 'número del Intervalo RR detectado')
            ylabel(app.UIRRSignalLength, 'duración (ms)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_heartbeat_detection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end